% description: Copyright 2018 Luca Della Santina
classdef Clarinet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClarinetUIFigure           matlab.ui.Figure
        EpochTree                  matlab.ui.container.Tree
        Lamp                       matlab.ui.control.Lamp
        Log                        matlab.ui.control.EditField
        UIAxes                     matlab.ui.control.UIAxes
        pnlToolbar                 matlab.ui.container.Panel
        btnFind                    matlab.ui.control.Button
        btnOpenData                matlab.ui.control.Button
        btnAbout                   matlab.ui.control.Button
        btnExport                  matlab.ui.control.Button
        btnSave                    matlab.ui.control.Button
        btnProcess                 matlab.ui.control.Button
        btnFilterSettings          matlab.ui.control.Button
        cmbProcessors              matlab.ui.control.DropDown
        btnBatchAdd                matlab.ui.control.Button
        btnBatchClear              matlab.ui.control.Button
        lblProcessors              matlab.ui.control.Label
        btnBatchOpen               matlab.ui.control.Button
        btnBatchSave               matlab.ui.control.Button
        btnBatchApply              matlab.ui.control.Button
        tblMeta                    matlab.ui.control.Table
        btnResponseType            matlab.ui.control.Switch
        cmbDevices                 matlab.ui.control.DropDown
        XScaleType                 matlab.ui.control.Switch
        btnEpochTypes              matlab.ui.control.Switch
        btnGroupVisualizationType  matlab.ui.container.ButtonGroup
        btnPlot2D                  matlab.ui.control.ToggleButton
        btnPlot3D                  matlab.ui.control.ToggleButton
        pntTags                    matlab.ui.container.ButtonGroup
        btnTagEmpty                matlab.ui.control.ToggleButton
        btnTagYellow               matlab.ui.control.ToggleButton
        btnTagGreen                matlab.ui.control.ToggleButton
        btnTagOrange               matlab.ui.control.ToggleButton
        btnTagBlue                 matlab.ui.control.ToggleButton
        btnTagRed                  matlab.ui.control.ToggleButton
        btnTagBlack                matlab.ui.control.ToggleButton
        btnTagAdd                  matlab.ui.control.Button
        btnTagRemove               matlab.ui.control.Button
    end

    
    properties (Access = private)
        Cell            = [] % Current cell data
        Conditions      = [] % Search conditions table
        SelectedEpochs  = [] % List of Epoch indexes seected using findEpochs
        dlgFind         = [] % Dialog window used for predicate search
        dlgAbout        = [] % Dialog window showing app info
        pipeline        = [] % Processing Pipeline list
        rootNode        = [] % Root node handle of the UITree component
        LocalFolder     = '' % Local folder in which settings and custom processors are stored
        selectedTagRows = [] % Row indexes the user selected in the Tags table
    end
    
    methods (Access = private)
        
        function populateEpochTree(app)
            app.EpochTree.clo; % Clear previous tree
            app.tblMeta.Data = [];
            
            exp = uitreenode(app.EpochTree,'Text',[app.Cell.get('label') ' (' app.Cell.experimentDate ')'],'NodeData',[]);
            app.rootNode = exp;
            for i = 1:numel(app.Cell.epochs)
                epoch = app.Cell.epochs(i);
                if strcmp(app.btnEpochTypes.Value, 'Searched') && (isempty(epoch.filtered) || ~epoch.filtered)
                    continue
                end
                
                if epoch.hasAttribute('tagColor') && ~strcmp(epoch.attributes('tagColor'),'')
                    tagColor = [epoch.attributes('tagColor') '.png'];
                else
                    tagColor = '';
                    epoch.attributes('tagColor') = tagColor;
                end
                uitreenode(exp,'Text',['Epoch ' num2str(epoch.get('epochNum')) datestr(epoch.get('epochTime'), ' (mmm dd, HH:MM:SS)')],...
                    'NodeData',epoch,'Icon', tagColor);
            end
            
            app.EpochTree.expand('all');
        end
        
        function plotEpoch(app, epochs)
            cla(app.UIAxes,'reset');
            app.UIAxes.XGrid = true;
            app.UIAxes.YGrid = true;
            app.UIAxes.ZGrid = true;
            
            epochNames = repmat({''}, numel(epochs),1);
            for i = 1:numel(epochs)
                epochNames{i} = ['Epoch ' num2str(epochs(i).get('epochNum'))]; %Remember epoch names for legend
                switch app.btnResponseType.Value
                    case 'Raw'
                        response = epochs(i).getResponse(app.cmbDevices.Value);
                    case 'Processed'
                        if epochs(i).hasDerivedResponse('filteredResponse', app.cmbDevices.Value)
                            response = epochs(i).getDerivedResponse('filteredResponse', app.cmbDevices.Value);
                        else
                            disp('No filtered Response available, using raw');
                            response = epochs(i).getResponse(app.cmbDevices.Value);
                        end
                end
                
                % Plot epoch in a 3D space if button is pressed, otherwise use 2D cartesian plane
                if app.btnPlot3D.Value
                    plot3(app.UIAxes, 1:numel(response.quantity), repmat(i, 1, numel(response.quantity)), response.quantity');
                else
                    plot(app.UIAxes, 1:numel(response.quantity), response.quantity');
                end
                
                % Define the range to display in the X and Y axis
                if i == 1
                    Xmax = numel(response.quantity);
                    Ymax = max(response.quantity);
                    Ymin = min(response.quantity);
                else
                    Xmax = max([Xmax, numel(response.quantity)]);
                    Ymax = max([Ymax, max(response.quantity)]);
                    Ymin = min([Ymin, min(response.quantity)]);
                end
                
                if numel(epochs)>1
                    hold(app.UIAxes, 'on');
                end
            end
            
            % Handle special cases before setting the Y axis
            if Ymin == Ymax % no signal or signal has a constant value
                Ymin = Ymin -1;
                Ymax = Ymax +1;
            elseif Ymin==0 && Ymax==1 % binary value (0-1)
                Ymin = -0.3;
                Ymax = 1.3;
            end
            
            if app.btnPlot3D.Value
                xlim(app.UIAxes, [1 Xmax]);
                ylim(app.UIAxes, [0 numel(epochs)+1]);
                zlim(app.UIAxes, [Ymin Ymax]);
            else
                xlim(app.UIAxes, [1 Xmax]);
                ylim(app.UIAxes, [Ymin Ymax]);
            end
            
            switch app.XScaleType.Value
                case 'Time (s)'     % Relabel 'XTick' With 'XTickLabel' Values scaled by sampleRate
                    meta = epochs(1).toStructure;
                    xt = get(app.UIAxes, 'XTick');
                    set(app.UIAxes, 'XTick', xt, 'XTickLabel', xt/meta.sampleRate);
                case 'Samples'      % Use original XTickLabels
                    xt = get(app.UIAxes, 'XTick');
                    set(app.UIAxes, 'XTick', xt, 'XTickLabel', xt);
            end
            legend(app.UIAxes, epochNames, 'Location','northeast');
        end
        
        function populateFilterList(app)
            import processors.*
            filters = meta.package.fromName('processors').FunctionList;
            filterNames = {filters.Name};
            filterNames = sort(filterNames);
            
            import customProcessors.*
            if ~isempty(meta.package.fromName('customProcessors'))
                customFilters = meta.package.fromName('customProcessors').FunctionList;
                customFilterNames = {customFilters.Name};
                customFilterNames = sort(customFilterNames);
                app.cmbProcessors.Items = [customFilterNames, filterNames];
            else
                app.cmbProcessors.Items = filterNames;
            end
            app.cmbProcessorsValueChanged;
        end
        
    end
    
    methods (Access = public)
        
        function result = findEpochs(app, conditions, criteria)
            % Search for epoch matching the stated conditions according to a specific criteria
            % conditions = table of conditions, each row contains: [ propery | operator | value ]
            % criteria = [ Any | All ] (e.g. "Any" means query returns epochs which meet any of the conditions)
            
            CONDITIONS_MAP = containers.Map({'numeric', 'string'},...
                {{'==', '>', '<', '>=', '<=', '~='}, {'strcmpi', 'strfind', 'strcmp', 'regexp'}});
            epochs = app.Cell.epochs;
            
            arrayfun(@(e) setFiltered(e, false), epochs)
            query = linq(1 : numel(epochs));
            
            rows = size(conditions, 1);
            for row = 1 : rows
                property    = conditions{row, 1}; % returns a cell with the property
                property    = property{:};
                operator    = conditions{row, 2};
                operator    = string(operator);
                value       = conditions{row, 3}; % returns a cell with the value
                value       = value{:}; % needed to retrieve the actual values from a cell
                
                % find out whether current operator is numeric or string
                operatorIdx = cellfun(@(keySet) ismember(operator, keySet), CONDITIONS_MAP.values);
                keys        = CONDITIONS_MAP.keys;
                operatorType= keys{operatorIdx};
                
                if strcmp(operatorType, 'numeric')
                    predicateCondition = str2func(strcat('@(data, values) any(data.get(''', property, ''')', operator, 'values)'));
                    value = str2double(value);
                else
                    predicateCondition = str2func(strcat('@(data, values) any(', operator, '(data.get(''', property, '''), values))'));
                end
                
                predicate = @(data) predicateCondition(data, value);
                switch criteria
                    case 'All'
                        query = query.where(@(index) predicate(epochs(index)));
                    case 'Any'
                        if row == 1
                            query = query.where(@(index) predicate(epochs(index)));
                        else
                            newquery = linq(1 : numel(epochs));
                            newquery = newquery.where(@(index) predicate(epochs(index)));
                            query = query.concat(newquery);
                        end
                end
            end
            filteredIndices = query.toArray();
            
            % Update the 'filtered' property of each epoch according to result
            arrayfun(@(e) setFiltered(e, true), epochs(filteredIndices));
            arrayfun(@(e) setFiltered(e, false), epochs(setdiff(1:numel(epochs), filteredIndices)));
            
            result                  = filteredIndices;
            app.SelectedEpochs      = filteredIndices;  % Store list of selected epochs indexes
            app.Conditions          = conditions;       % Store conditions table for later
            app.btnEpochTypes.Value = 'Searched';
            app.btnEpochTypesValueChanged;
            
            %if numel(filteredIndices) > 0
            %    [p, v] = app.Cell.getUniqueParamValues(filteredIndices);
            %    result = KeyValueEntity(containers.Map(p, v));
            %else
            %    result = 'No matching records found !';
            %end
            
            function setFiltered(epoch, tf)
                epoch.filtered = tf;
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Make sure there is a local folder in MATLAB's user path where to store settings
            if ~isfolder([userpath filesep 'Clarinet'])
                mkdir([userpath filesep 'Clarinet']);
                disp(['Creating a folder where all Clarinet settings will be stored: ' userpath filesep 'Clarinet']);
            end
            if ~isfolder([userpath filesep 'Clarinet' filesep '+customProcessors'])
                mkdir([userpath filesep 'Clarinet' filesep '+customProcessors']);
            end
            app.LocalFolder = [userpath filesep 'Clarinet'];
            addpath(app.LocalFolder);
            
            app.populateFilterList; % Load the available epoch processors
        end

        % Button pushed function: btnOpenData
        function btnOpenDataButtonPushed(app, event)
            [fName, PathName] = uigetfile({'*.h5;*.mat','Supported formats (*.h5, *.mat)'; '*.h5','Symphony files (*.h5)';'*.mat','Clarinet files (*.mat)'}, 'Select experiments', 'MultiSelect', 'on');
            app.Lamp.Color = 'Yellow';
            app.Log.Value = ['Loading ' fName '...'];
            pause(0.2);
            
            app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
            if iscell(fName)
                cd(PathName);
                nbfiles = length(fName);
            elseif fName ~= 0
                cd(PathName);
                nbfiles = 1;
            else
                nbfiles = 0;
            end
            
            import parsers.*
            dlg = uiprogressdlg(app.ClarinetUIFigure,'Title',['Loading ' fName],'Indeterminate','on');
            if nbfiles == 1
                [~,~,ext] = fileparts(fName);
                
                switch ext
                    case '.h5'
                        version = SymphonyParser.getVersion(fName);
                        if version == 2
                            ref = SymphonyV2Parser(fName);
                        else
                            ref = SymphonyV1Parser(fName);
                        end
                        ref.parse;
                        data = ref.getResult;
                        app.Cell = data{end};
                    case '.mat'
                        data = load(fName, 'Cell');
                        app.Cell = data.Cell;
                end
                
                app.populateEpochTree;
                cla(app.UIAxes);
                
                app.btnSave.Enable      = 'on';
                app.btnExport.Enable    = 'off';
                app.btnFind.Enable      = 'on';
                app.btnBatchAdd.Enable  = 'off';
                app.btnBatchSave.Enable = 'off';
                app.btnBatchOpen.Enable = 'on';
                app.btnBatchClear.Enable= 'on';
                app.btnProcess.Enable   = 'off';
                app.btnTagEmpty.Value   = true;
            else
                app.btnSave.Enable      = 'off';
                app.btnExport.Enable    = 'off';
                app.btnFind.Enable      = 'off';
                app.btnBatchAdd.Enable  = 'off';
                app.btnBatchSave.Enable = 'off';
                app.btnBatchOpen.Enable = 'off';
                app.btnBatchClear.Enable= 'off';
                app.btnProcess.Enable   = 'off';
                app.btnTagEmpty.Value   = true;
            end
            
            close(dlg);
            app.Log.Value = ['Loading ' fName '...DONE'];
            app.Lamp.Color = 'Green';
        end

        % Selection changed function: EpochTree
        function EpochTreeSelectionChanged(app, event)
            selectedNodes = app.EpochTree.SelectedNodes;
            
            if numel(selectedNodes) == 1
                epoch = selectedNodes.NodeData;     % Retrieve epoch data
                if isempty(epoch)
                    app.tblMeta.Data = app.Cell.toTable;
                    app.cmbDevices.Items = {};
                    app.btnTagEmpty.Value = true;
                    return
                end
                if epoch.hasAttribute('tagColor')
                    switch epoch.attributes('tagColor')
                        case 'yellow',  app.btnTagYellow.Value  = true;
                        case 'green',   app.btnTagGreen.Value   = true;
                        case 'orange',  app.btnTagOrange.Value  = true;
                        case 'red',     app.btnTagRed.Value     = true;
                        case 'blue',    app.btnTagBlue.Value    = true;
                        case 'black',   app.btnTagBlack.Value   = true;
                        otherwise,      app.btnTagEmpty.Value   = true;
                    end
                else
                    app.btnTagEmpty.Value = true;
                end
                if epoch.hasAttribute('pipeline')
                    pipe = epoch.attributes('pipeline');
                    tip = {['Epoch ' num2str(epoch.attributes('epochNum')) ' was processed with:']};
                    for i=1:numel(pipe)
                        tip(end+1) = {[num2str(i) ') ' pipe(i).name]}; %#ok
                    end
                    app.EpochTree.Tooltip    = tip;
                    app.btnBatchApply.Enable = 'on';
                else
                    app.EpochTree.Tooltip    = '';
                    app.btnBatchApply.Enable = 'off';
                end
                app.cmbDevices.Items = epoch.get('devices'); % Populate available devices
                app.tblMeta.Data = epoch.toTable;            % Populate metadata table
                app.plotEpoch(epoch);                        % Plot epoch data on screen
                
                app.btnExport.Enable    = 'on';
                app.btnBatchAdd.Enable  = 'on';
                app.btnTagAdd.Enable    = 'on';
                app.btnTagRemove.Enable = 'on';
            elseif numel(selectedNodes) > 1
                app.tblMeta.Data= [];
                for i= numel(selectedNodes):-1:1
                    epochs(i) = selectedNodes(i).NodeData;     % Retrieve epoch data
                end
                app.cmbDevices.Items = epochs(1).get('devices'); % Populate available devices
                app.plotEpoch(epochs);               % Plot epoch data on screen
                
                app.btnExport.Enable    = 'on';
                app.btnBatchAdd.Enable  = 'on';
                app.btnTagAdd.Enable    = 'on';
                app.btnTagRemove.Enable = 'off';
            else
                app.btnExport.Enable    = 'off';
                app.btnBatchAdd.Enable  = 'off';
                app.btnProcess.Enable   = 'off';
                app.btnTagAdd.Enable    = 'on';
                app.btnTagRemove.Enable = 'off';
            end
        end

        % Button pushed function: btnExport
        function btnExportButtonPushed(app, event)
            selectedNodes = app.EpochTree.SelectedNodes;
            
            if numel(selectedNodes) >= 1
                for i= numel(selectedNodes):-1:1
                    epoch = selectedNodes(i).NodeData;
                    meta = epoch.toStructure;
                    
                    % Filter the raw response if no previous filter was applied or user selected 'Raw'
                    switch app.btnResponseType.Value
                        case 'Raw'
                            response = epoch.getResponse(app.cmbDevices.Value);
                        case 'Processed'
                            if epoch.hasDerivedResponse('filteredResponse', app.cmbDevices.Value)
                                response = epoch.getDerivedResponse('filteredResponse', app.cmbDevices.Value);
                            else
                                response = epoch.getResponse(app.cmbDevices.Value); % Use raw response if no filtered is available
                            end
                    end
                    
                    epochs(i).epoch = response.quantity'; % Retrieve epoch data
                    epochs(i).meta  = meta;               % Retrieve epoch metadata
                end
                uisave({'epochs'},'SelectedEpochs.mat');
                
                app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
                app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            end
        end

        % Button pushed function: btnAbout
        function btnAboutButtonPushed(app, event)
            if isempty(app.dlgAbout)  || ~isprop(app.dlgAbout,'UIFigure')
                app.dlgAbout = DialogAbout;

                % Center the dialog on main window
                pos = app.dlgAbout.UIFigure.Position;
                posClarinet = app.ClarinetUIFigure.Position;
                pos(1) = ceil(posClarinet(1)+posClarinet(3)/2-pos(3)/2);
                pos(2) = ceil(posClarinet(2)+posClarinet(4)/2-pos(4)/2);
                app.dlgAbout.UIFigure.Position = pos;
            end
            app.dlgAbout.UIFigure.Visible  = 'on';
        end

        % Value changed function: cmbProcessors
        function cmbProcessorsValueChanged(app, event)
            import processors.*
            import customProcessors.*
            
            % Restrieve default settings
            filterName = app.cmbProcessors.Value;
            settings = feval(filterName);
            
            if numel(fieldnames(settings)) > 0
                app.btnFilterSettings.Enable = 'on';
            else
                app.btnFilterSettings.Enable = 'off';
            end
            
            app.cmbProcessors.Tooltip = help(filterName);
            app.btnFilterSettings.UserData = settings;
        end

        % Button pushed function: btnProcess
        function btnProcessButtonPushed(app, event)
            import processors.*
            import customProcessors.*
            
            if isempty(app.pipeline)
                app.btnBatchAddButtonPushed; % Add the current processo and use just that one
            end
            
            selectedNodes = app.EpochTree.SelectedNodes;
            for i=1:numel(selectedNodes)
                % Use raw response if no previous filter was applied or user pressed the 'Raw' switch
                epoch   = selectedNodes(i).NodeData;
                if strcmp(app.btnResponseType.Value,'Raw') || ~epoch.hasDerivedResponse('filteredResponse', app.cmbDevices.Value)
                    response = epoch.getResponse(app.cmbDevices.Value);
                    epoch.addDerivedResponse('filteredResponse', response, app.cmbDevices.Value);
                end
                
                % Apply all processors in the pipeline to the epoch
                for j = 1:numel(app.pipeline)
                    proc    = app.pipeline(j);
                    epoch   = feval(proc.name, epoch, app.cmbDevices.Value, proc.settings);
                end
                epoch.attributes('pipeline') = app.pipeline;
                selectedNodes(i).NodeData = epoch;
            end
            
            
            app.btnResponseType.Value = 'Processed';
            app.btnResponseTypeValueChanged;
            app.EpochTreeSelectionChanged;
        end

        % Button pushed function: btnFilterSettings
        function btnFilterSettingsButtonPushed(app, event)
            settings = app.btnFilterSettings.UserData;
            
            newSettings = editStruct(settings);
            if ~isempty(newSettings)
                app.btnFilterSettings.UserData = newSettings;
            end
        end

        % Button pushed function: btnFind
        function btnFindButtonPushed(app, event)
            if isempty(app.dlgFind) || ~isprop(app.dlgFind,'UIFigure')
                app.dlgFind = DialogFind(app);
            
                % Center the dialog on main window
                pos = app.dlgFind.UIFigure.Position;
                posClarinet = app.ClarinetUIFigure.Position;
                pos(1) = ceil(posClarinet(1)+posClarinet(3)/2-pos(3)/2);
                pos(2) = ceil(posClarinet(2)+posClarinet(4)/2-pos(4)/2);
                app.dlgFind.UIFigure.Position = pos;
            else
                app.dlgFind.UIFigure.Visible = 'on';
            end
        end

        % Value changed function: btnResponseType
        function btnResponseTypeValueChanged(app, event)
            switch app.btnResponseType.Value
                case 'Raw'
                    app.btnResponseType.FontColor = [0 0 0];
                case 'Processed'
                    app.btnResponseType.FontColor = [0.85 0.33 0.10];
            end
            app.EpochTreeSelectionChanged;
        end

        % Value changed function: cmbDevices
        function cmbDevicesValueChanged(app, event)
            app.EpochTreeSelectionChanged;
        end

        % Value changed function: XScaleType
        function XScaleTypeValueChanged(app, event)
            app.EpochTreeSelectionChanged;
        end

        % Button pushed function: btnSave
        function btnSaveButtonPushed(app, event)
            % Synch current cell with updated data from EpochTree nodes
            nodes = app.rootNode.Children;
            for i = 1 : numel(nodes)
                app.Cell.epochs(i) = nodes(i).NodeData;
            end
            
            % Save cell on disk
            Cell = app.Cell; %#ok
            uisave({'Cell'},[app.Cell.h5File '.mat']);
            
            app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
        end

        % Value changed function: btnEpochTypes
        function btnEpochTypesValueChanged(app, event)
            switch app.btnEpochTypes.Value
                case 'All'
                    app.btnEpochTypes.FontColor = [0 0 0];
                    if ~isempty(app.Cell)
                        app.populateEpochTree;
                    end
                case 'Searched'
                    app.btnEpochTypes.FontColor = [0.85 0.33 0.10];
                    if ~isempty(app.Cell)
                        app.populateEpochTree;
                    end
            end
        end

        % Selection changed function: btnGroupVisualizationType
        function btnGroupVisualizationTypeSelectionChanged(app, event)
            app.EpochTreeSelectionChanged;
        end

        % Close request function: ClarinetUIFigure
        function ClarinetUIFigureCloseRequest(app, event)
            delete(app.dlgAbout)
            delete(app.dlgFind)
            delete(app)
        end

        % Button pushed function: btnBatchAdd
        function btnBatchAddButtonPushed(app, event)
            % Append selected processor to the pipeline
            processor.name          = app.cmbProcessors.Value;
            processor.settings      = app.btnFilterSettings.UserData;
            if isempty(app.pipeline)
                app.pipeline        = processor;
            else
                app.pipeline(end+1) = processor;
            end
            
            tip = {'Process selected epochs with:', ''};
            for i=1:numel(app.pipeline)
                tip(end+1) = {[num2str(i) ') ' app.pipeline(i).name]}; %#ok
            end
            app.btnProcess.Tooltip = tip;
            app.btnBatchSave.Enable = 'on';
            app.btnProcess.Enable   = 'on';
        end

        % Button pushed function: btnBatchClear
        function btnBatchClearButtonPushed(app, event)
            app.pipeline = [];
            app.btnProcess.Tooltip = {'Process selected epochs with:'};
            app.btnBatchSave.Enable = 'off';
            app.btnProcess.Enable   = 'off';
        end

        % Selection changed function: pntTags
        function pntTagsSelectionChanged(app, event)
            selectedButton = app.pntTags.SelectedObject;
            for i=1:numel(app.EpochTree.SelectedNodes)
                if strcmp(selectedButton.Icon, 'white.png')
                    app.EpochTree.SelectedNodes(i).Icon = '';
                    app.EpochTree.SelectedNodes(i).NodeData.attributes('tagColor') = '';
                else
                    app.EpochTree.SelectedNodes(i).Icon = selectedButton.Icon;
                    [~,colorName,~] = fileparts(selectedButton.Icon);
                    app.EpochTree.SelectedNodes(i).NodeData.attributes('tagColor') = colorName;
                end
            end
        end

        % Button pushed function: btnBatchOpen
        function btnBatchOpenButtonPushed(app, event)
            [fName, PathName] = uigetfile({'*.mat','Clarinet pipelines (*.mat)'}, 'Select experiments', 'MultiSelect', 'off');
            app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
            data = load(fullfile(PathName, fName), 'pipeline');
            app.pipeline = data.pipeline;
            
            tip = {'Add to processing pipeline', '', 'Current pipeline:'};
            for i=1:numel(app.pipeline)
                tip(end+1) = {[num2str(i) ') ' app.pipeline(i).name]}; %#ok
            end
            app.btnBatchAdd.Tooltip = tip;
            
            app.btnBatchAdd.Enable   = 'on';
            app.btnBatchClear.Enable = 'on';
            app.btnBatchSave.Enable  = 'on';
            app.btnProcess.Enable    = 'on';
        end

        % Button pushed function: btnBatchSave
        function btnBatchSaveButtonPushed(app, event)
            % Save processing pipeline on disk
            pipeline = app.pipeline; %#ok
            uisave({'pipeline'},'pipeline.mat');
            
            app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
        end

        % Button pushed function: btnTagAdd
        function btnTagAddButtonPushed(app, event)
            Tag.Name = 'CustomTag';
            Tag.Value = 'true';
            
            newTag = editStruct(Tag, 'Add Tag');
            if ~isempty(newTag)
                % try to convert the value to number, if not possible store it as string
                value = str2double(newTag.Value);
                if ~isnan(value)
                    newTag.Value = value;
                end
                
                % Add the new Tag/Value pair to the list of attributes for selected epochs
                for i=1:numel(app.EpochTree.SelectedNodes)
                    app.EpochTree.SelectedNodes(i).NodeData.attributes(newTag.Name) = newTag.Value;
                end
                app.EpochTreeSelectionChanged;
            end
        end

        % Button pushed function: btnTagRemove
        function btnTagRemoveButtonPushed(app, event)
            if ~isempty(app.selectedTagRows)
                Tag = app.tblMeta.Data{app.selectedTagRows(:,1),1};
                Tag = Tag{:};
                for i=1:numel(app.EpochTree.SelectedNodes)
                    remove(app.EpochTree.SelectedNodes(i).NodeData.attributes, Tag);
                end
                app.EpochTreeSelectionChanged;
            end            
        end

        % Cell selection callback: tblMeta
        function tblMetaCellSelection(app, event)
            indices = event.Indices;
            app.selectedTagRows = indices;
        end

        % Button pushed function: btnBatchApply
        function btnBatchApplyButtonPushed(app, event)
            selectedNodes = app.EpochTree.SelectedNodes;
            % Load processing pipeline from selected epoch            
            if numel(selectedNodes) == 1
                epoch = selectedNodes.NodeData;     % Retrieve epoch data
                if epoch.hasAttribute('pipeline')
                    app.pipeline = epoch.attributes('pipeline');

                    tip = {'Process selected epochs with:', ''};
                    for i=1:numel(app.pipeline)
                        tip(end+1) = {[num2str(i) ') ' app.pipeline(i).name]}; %#ok
                    end
                    app.btnProcess.Tooltip = tip;
                    
                    app.btnBatchAdd.Enable   = 'on';
                    app.btnBatchClear.Enable = 'on';
                    app.btnBatchSave.Enable  = 'on';
                    app.btnProcess.Enable    = 'on';                    
                end
            end            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClarinetUIFigure = uifigure;
            app.ClarinetUIFigure.Name = 'Clarinet';
            app.ClarinetUIFigure.CloseRequestFcn = createCallbackFcn(app, @ClarinetUIFigureCloseRequest, true);
            app.EpochTree = uitree(app.ClarinetUIFigure);
            app.EpochTree.Multiselect = 'on';
            app.EpochTree.SelectionChangedFcn = createCallbackFcn(app, @EpochTreeSelectionChanged, true);
            app.Lamp = uilamp(app.ClarinetUIFigure);
            app.Lamp.Tooltip = {'App status:'; 'Green = OK'; 'Yellow = Busy'; 'Red = Error'};
            app.Log = uieditfield(app.ClarinetUIFigure, 'text');
            app.Log.Tooltip = {'Status bar keeping track of last event occurred'};
            app.Log.Value = 'Welcome to Clarinet, open an experiment to begin';
            app.UIAxes = uiaxes(app.ClarinetUIFigure);
            app.pnlToolbar = uipanel(app.ClarinetUIFigure);
            app.btnFind = uibutton(app.pnlToolbar, 'push');
            app.btnFind.ButtonPushedFcn = createCallbackFcn(app, @btnFindButtonPushed, true);
            app.btnFind.Icon = '48px-search.png';
            app.btnFind.Enable = 'off';
            app.btnFind.Tooltip = {'Select epochs meeting specific conditions'};
            app.btnFind.Text = 'Search';
            app.btnOpenData = uibutton(app.pnlToolbar, 'push');
            app.btnOpenData.ButtonPushedFcn = createCallbackFcn(app, @btnOpenDataButtonPushed, true);
            app.btnOpenData.Icon = '48px-open.png';
            app.btnOpenData.Tooltip = {'Load epochs from an experiment'};
            app.btnOpenData.Text = 'Open';
            app.btnAbout = uibutton(app.pnlToolbar, 'push');
            app.btnAbout.ButtonPushedFcn = createCallbackFcn(app, @btnAboutButtonPushed, true);
            app.btnAbout.Icon = '48px-info.png';
            app.btnAbout.Tooltip = {'Information about Clarinet'};
            app.btnAbout.Text = 'About';
            app.btnExport = uibutton(app.pnlToolbar, 'push');
            app.btnExport.ButtonPushedFcn = createCallbackFcn(app, @btnExportButtonPushed, true);
            app.btnExport.Icon = '48px-export.png';
            app.btnExport.Enable = 'off';
            app.btnExport.Tooltip = {'Export selected epochs data to .mat file'};
            app.btnExport.Text = {'Export'; ''};
            app.btnSave = uibutton(app.pnlToolbar, 'push');
            app.btnSave.ButtonPushedFcn = createCallbackFcn(app, @btnSaveButtonPushed, true);
            app.btnSave.Icon = '48px-save.png';
            app.btnSave.Enable = 'off';
            app.btnSave.Tooltip = {'Save the experiment into .mat file'};
            app.btnSave.Text = 'Save';
            app.btnProcess = uibutton(app.pnlToolbar, 'push');
            app.btnProcess.ButtonPushedFcn = createCallbackFcn(app, @btnProcessButtonPushed, true);
            app.btnProcess.Icon = '48px-execute.png';
            app.btnProcess.Enable = 'off';
            app.btnProcess.Tooltip = {'Process selected epochs'};
            app.btnProcess.Text = 'Process';
            app.btnFilterSettings = uibutton(app.pnlToolbar, 'push');
            app.btnFilterSettings.ButtonPushedFcn = createCallbackFcn(app, @btnFilterSettingsButtonPushed, true);
            app.btnFilterSettings.Icon = '48px-settings.png';
            app.btnFilterSettings.Tooltip = {'Edit settings for chosen filter'};
            app.btnFilterSettings.Text = 'Settings';
            app.cmbProcessors = uidropdown(app.pnlToolbar);
            app.cmbProcessors.Items = {};
            app.cmbProcessors.ValueChangedFcn = createCallbackFcn(app, @cmbProcessorsValueChanged, true);
            app.cmbProcessors.Value = {};
            app.btnBatchAdd = uibutton(app.pnlToolbar, 'push');
            app.btnBatchAdd.ButtonPushedFcn = createCallbackFcn(app, @btnBatchAddButtonPushed, true);
            app.btnBatchAdd.Icon = '48px-List-add.png';
            app.btnBatchAdd.Enable = 'off';
            app.btnBatchAdd.Tooltip = {'Add processor to pipeline'};
            app.btnBatchAdd.Text = 'Add to Pipeline';
            app.btnBatchClear = uibutton(app.pnlToolbar, 'push');
            app.btnBatchClear.ButtonPushedFcn = createCallbackFcn(app, @btnBatchClearButtonPushed, true);
            app.btnBatchClear.Icon = '48px-list-clear.png';
            app.btnBatchClear.Enable = 'off';
            app.btnBatchClear.Tooltip = {'Clear processing pipeline'};
            app.btnBatchClear.Text = '';
            app.lblProcessors = uilabel(app.pnlToolbar);
            app.lblProcessors.Text = 'Epoch processors';
            app.btnBatchOpen = uibutton(app.pnlToolbar, 'push');
            app.btnBatchOpen.ButtonPushedFcn = createCallbackFcn(app, @btnBatchOpenButtonPushed, true);
            app.btnBatchOpen.Icon = '48px-open.png';
            app.btnBatchOpen.Enable = 'off';
            app.btnBatchOpen.Tooltip = {'Load a previously saved processing pipeline'};
            app.btnBatchOpen.Text = '';
            app.btnBatchSave = uibutton(app.pnlToolbar, 'push');
            app.btnBatchSave.ButtonPushedFcn = createCallbackFcn(app, @btnBatchSaveButtonPushed, true);
            app.btnBatchSave.Icon = '48px-save.png';
            app.btnBatchSave.Enable = 'off';
            app.btnBatchSave.Tooltip = {'Sace current pipeline'};
            app.btnBatchSave.Text = '';
            app.btnBatchApply = uibutton(app.pnlToolbar, 'push');
            app.btnBatchApply.ButtonPushedFcn = createCallbackFcn(app, @btnBatchApplyButtonPushed, true);
            app.btnBatchApply.Icon = '48px-pipeline-apply.png';
            app.btnBatchApply.Enable = 'off';
            app.btnBatchApply.Tooltip = {'Load processing pipeline used for selected epoch'};
            app.btnBatchApply.Text = '';
            app.tblMeta = uitable(app.ClarinetUIFigure);
            app.tblMeta.ColumnName = {'Tag'; 'Value'};
            app.tblMeta.RowName = {};
            app.tblMeta.CellSelectionCallback = createCallbackFcn(app, @tblMetaCellSelection, true);
            app.btnResponseType = uiswitch(app.ClarinetUIFigure, 'slider');
            app.btnResponseType.Items = {'Raw', 'Processed'};
            app.btnResponseType.ValueChangedFcn = createCallbackFcn(app, @btnResponseTypeValueChanged, true);
            app.btnResponseType.Tooltip = {'Epoch version to process'};
            app.btnResponseType.Value = 'Raw';
            app.cmbDevices = uidropdown(app.ClarinetUIFigure);
            app.cmbDevices.Items = {};
            app.cmbDevices.ValueChangedFcn = createCallbackFcn(app, @cmbDevicesValueChanged, true);
            app.cmbDevices.Tooltip = {'Device generating epochs'};
            app.cmbDevices.Value = {};
            app.XScaleType = uiswitch(app.ClarinetUIFigure, 'slider');
            app.XScaleType.Items = {'Time (s)', 'Samples'};
            app.XScaleType.ValueChangedFcn = createCallbackFcn(app, @XScaleTypeValueChanged, true);
            app.XScaleType.Tooltip = {'Switch the abscissa scale'};
            app.XScaleType.Value = 'Time (s)';
            app.btnEpochTypes = uiswitch(app.ClarinetUIFigure, 'slider');
            app.btnEpochTypes.Items = {'All', 'Searched'};
            app.btnEpochTypes.ValueChangedFcn = createCallbackFcn(app, @btnEpochTypesValueChanged, true);
            app.btnEpochTypes.Tooltip = {'Epochs to display'};
            app.btnEpochTypes.Value = 'All';
            app.btnGroupVisualizationType = uibuttongroup(app.ClarinetUIFigure);
            app.btnGroupVisualizationType.SelectionChangedFcn = createCallbackFcn(app, @btnGroupVisualizationTypeSelectionChanged, true);
            app.btnPlot2D = uitogglebutton(app.btnGroupVisualizationType);
            app.btnPlot2D.Text = '2D';
            app.btnPlot2D.Value = true;
            app.btnPlot3D = uitogglebutton(app.btnGroupVisualizationType);
            app.btnPlot3D.Text = '3D';
            app.pntTags = uibuttongroup(app.ClarinetUIFigure);
            app.pntTags.SelectionChangedFcn = createCallbackFcn(app, @pntTagsSelectionChanged, true);
            app.pntTags.Tooltip = {'Color Tag selected epochs'};
            app.btnTagEmpty = uitogglebutton(app.pntTags);
            app.btnTagEmpty.Icon = 'white.png';
            app.btnTagEmpty.Text = '';
            app.btnTagEmpty.Value = true;
            app.btnTagYellow = uitogglebutton(app.pntTags);
            app.btnTagYellow.Icon = 'yellow.png';
            app.btnTagYellow.Text = '';
            app.btnTagGreen = uitogglebutton(app.pntTags);
            app.btnTagGreen.Icon = 'green.png';
            app.btnTagGreen.Text = '';
            app.btnTagOrange = uitogglebutton(app.pntTags);
            app.btnTagOrange.Icon = 'orange.png';
            app.btnTagOrange.Text = '';
            app.btnTagBlue = uitogglebutton(app.pntTags);
            app.btnTagBlue.Icon = 'blue.png';
            app.btnTagBlue.Text = '';
            app.btnTagRed = uitogglebutton(app.pntTags);
            app.btnTagRed.Icon = 'red.png';
            app.btnTagRed.Text = '';
            app.btnTagBlack = uitogglebutton(app.pntTags);
            app.btnTagBlack.Icon = 'black.png';
            app.btnTagBlack.Text = '';
            app.btnTagAdd = uibutton(app.ClarinetUIFigure, 'push');
            app.btnTagAdd.ButtonPushedFcn = createCallbackFcn(app, @btnTagAddButtonPushed, true);
            app.btnTagAdd.Icon = '48px-List-add.png';
            app.btnTagAdd.Enable = 'off';
            app.btnTagAdd.Tooltip = {'Add a custom tag to selected epochs'};
            app.btnTagAdd.Text = '';
            app.btnTagRemove = uibutton(app.ClarinetUIFigure, 'push');
            app.btnTagRemove.ButtonPushedFcn = createCallbackFcn(app, @btnTagRemoveButtonPushed, true);
            app.btnTagRemove.Icon = '48px-List-remove.png';
            app.btnTagRemove.Enable = 'off';
            app.btnTagRemove.Tooltip = {'Remve selected tag from the list'};
            app.btnTagRemove.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Clarinet

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClarinetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClarinetUIFigure)
        end
    end
end